classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainImage                      matlab.ui.control.UIAxes
        ButtonGroup                    matlab.ui.container.ButtonGroup
        InteractiveButton              matlab.ui.control.ToggleButton
        ManualButton                   matlab.ui.control.ToggleButton
        ChangeimagesetButton           matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        AnalyzethisintervalButton      matlab.ui.control.Button
        OpacityLabel                   matlab.ui.control.Label
        OpacitySlider                  matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        manualModeOpacitySlider        matlab.ui.control.Slider
        ChangesLabel                   matlab.ui.control.Label
        SmallChangesCheckBox           matlab.ui.control.CheckBox
        LargeChangesCheckBox           matlab.ui.control.CheckBox
        G07CV2021Label                 matlab.ui.control.Label
        AreaSizeThresholdListBoxLabel  matlab.ui.control.Label
        AreaSizeThresholdListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        images % Description
        images1_crop = {} % Description
        images2_crop = {}% Description
        images1_crop_gray = {} % Description
        images2_crop_gray = {} % Description
        images2_crop_bright = {} % Description
        images2_crop_bright_gray = {} % Description
        difference_images = {} % Description
        difference_images_clean = {} % Description
        change_maps = {} % Description
        main_image_transparent_axis % Description
        change_map_axis % Description
        filenames % Description
        currentInterval = 1 % Description
        processedIntervals % Description
        previewCheckButtonsManual % Description
        checkedIndices = [] % Description
        manualMode = 0 % Description
        intervalMatrix % Description
        cluster_masks_change_map = {} % Description
        pic1_clusters_colors = {}
        pic2_clusters_colors = {}
        clusterChangesCheckBoxes = {} % Description
        clusterChangesColorBoxes = {}
        change_map_clusters_axis = {} % Description
        smallChanges = {} % Description
        bigChanges = {}
        small_changes_axis % Description
        large_changes_axis % Description
        PreviewImages
        sizeThreshModeVal = 1;
    end
    
    methods (Access = private)
        
        function createThumbnails(app)
            %%HARDCODE
           % set the minimum padding we need as gap between images
           minimum_padding = 70;
           %set maximum height of images
           maxheight = 100;
           %Set PreviewBar width to 90% figure width
           maxwidth_bar = app.UIFigure.Position(3)*0.9;
           %Set Preview Bar Position
           x_start = (app.UIFigure.Position(3)-maxwidth_bar)/2;
           y_start = app.Slider.Position(2) + 10 ;
          
           % number of images
           imgcnt = size(app.images,2);
          
           % resize images to height of preview bar, keeping aspect ratio
       	   h = size(app.images{1},1);
           w = size(app.images{1},2);
           ratio = w/h;
          
          
           %Calculate barwidth of with all images at maxheight and minimum Padding
           picwidth_at_maxheight = floor(maxheight*ratio);
          
           barwidth_at_maxheight = imgcnt * picwidth_at_maxheight + (imgcnt-1)*minimum_padding;
          
          
           %adjust image size and padding
           if barwidth_at_maxheight > maxwidth_bar
              
               %if too wide reduce height and keep passing
               pic_must_width = floor((maxwidth_bar - (imgcnt-1)*minimum_padding)/imgcnt);
               pic_must_height = floor(pic_must_width/ratio);
               must_padding = minimum_padding;
              
               assert(pic_must_height<=maxheight);
              
           elseif barwidth_at_maxheight < maxwidth_bar
                  
               %if not wide enough, increase padding
               must_padding = (maxwidth_bar-barwidth_at_maxheight)/(imgcnt-1);
               pic_must_width = picwidth_at_maxheight;
               pic_must_height = maxheight;
              
              
           else
               % If it works, it works
               must_padding = minimum_padding;
               pic_must_width = picwidth_at_maxheight;
               pic_must_height = maxheight;
              
           end
         
          
           %Create PreviewImages at desired Positions
          
           for i=1:imgcnt
              app.PreviewImages{i} = uiimage(app.UIFigure);
              x_Pos = (i-1)*(pic_must_width + must_padding);
              app.PreviewImages{i}.Position = [x_start+x_Pos, y_start, pic_must_width,pic_must_height];
              app.PreviewImages{i}.ImageSource = imresize(app.images{i},[pic_must_height,pic_must_width]);      
           end
           %Setup Slider
           setup_progressbar(app, maxwidth_bar, imgcnt, pic_must_width, must_padding);
          
           %Images are Reset in ChangeimagesetButtonPushed!!
      
        end
        
        function alignPictures(app, im1, im2, interval)
            [app.images1_crop{interval}, app.images1_crop_gray{interval},...
               app.images2_crop{interval}, app.images2_crop_gray{interval},...
               app.images2_crop_bright{interval} , app.images2_crop_bright_gray{interval}] = alignPics(im1, im2, true, false);
        end
        
        function pca_kmeans(app, h, p, interval)
            app.change_maps{interval} = PCA_KMEANS_4DIEPOLD(app.difference_images_clean{interval}, h, p);
        end
        
        function setup_progressbar(app, totalwidth, imcnt, imwidth, paddingwidth)
           %Set Left Slider End over Center of image 1
           x_Start_Slider = app.PreviewImages{1}.Position(1) + floor(imwidth/2);
           app.Slider.Position(1) = x_Start_Slider;
           %Set Right Slider End over Center of last image
           width_Slider = totalwidth - imwidth;          
           app.Slider.Position(3) = width_Slider;
          
           %Set Slider imagecnt ticks in equivalent spacings
           app.Slider.MajorTicks = linspace(0,100,imcnt);
          
           %Set Slider Tick Labels
           months = ["Jan", "Feb", "Mar", "Apr", "Mai", "Jun", "Jul", "Aug", "Sep", "Oct", "Nov", "Dec"];
           labels = [];
           for i=1:length(app.filenames)
               year = extractBefore(app.filenames(i), "_");
               month = extractBetween(app.filenames(i), "_", ".");
               labels = [labels; months(str2num(month)) + " " + year];
           end
           app.Slider.MajorTickLabels = labels;
          
            createManualCheckBoxes(app);
       end

        
        % returns the slider boundaries of current Tick interval for the
        % slider at position value
        function [leftval, rightval, leftindex] = getSliderBoundaries(app, value)
            intervals_slider = app.Slider.MajorTicks;
            % "out of range" check
            value = max(intervals_slider(1), value);
            value = min(value, intervals_slider(end)-1);
            
            elements_smaller = length(intervals_slider(intervals_slider <= value));
            new_interval = elements_smaller;
            leftval = intervals_slider(new_interval);
            rightval = intervals_slider(new_interval + 1);
            leftindex = new_interval;
        end
        
        function alpha = getAlpha(app, value, leftval, rightval)
            intervals_slider = app.Slider.MajorTicks;
            % "out of range" check
            value = max(intervals_slider(1), value);
            value = min(value, intervals_slider(end)-1);
            
            % value == rightval --> alpha = 1
            % value == leftval --> alpha = 0
            alpha = (value - leftval) / (rightval - leftval);
        end
        
        function processImages(app, interval, title)
            if ~isempty(app.images1_crop{interval})
                disp("#################################### already processed interval " + interval);
                return
            end
            % get image indexes for current interval
            [id1, id2] = getImagesForInterval(app, interval);            
            disp("Processing images" + app.filenames(id1) + " and " + app.filenames(id2));
            
            fig = app.UIFigure;
            progress = uiprogressdlg(fig, "Title", title,...
               "Message", "Processing Images...",...
               'Cancelable',"off");
            
            % Progress Update
            progress.Value = .1;
            progress.Message = "Aligning Pictures with SURF...";
            
            % Picture Alignment
            alignPictures(app, app.images{id1}, app.images{id2}, interval);
            
            % Progress Update
            progress.Value = .2;
            progress.Message = "Computing difference Image...";
            
            % Get Difference Image
            [app.difference_images{interval}, app.difference_images_clean{interval}, ~]...
               = QuadModell(app.images1_crop_gray{interval}, app.images2_crop_gray{interval}, 3, 1);
            
            % Progress Update
            progress.Value = .4;
            progress.Message = "Detecting Changes...";
           
            % do the work!
            pca_kmeans(app, 5, progress, interval);
            
            % get clustered changes
            [app.cluster_masks_change_map{interval}, app.pic1_clusters_colors{interval},...
                app.pic2_clusters_colors{interval}]...
                = clusterChanges(app.images1_crop{interval}, app.images1_crop_gray{interval},...
                app.images2_crop{interval}, app.images2_crop_gray{interval}, app.change_maps{interval}, true, false);
            
            [app.smallChanges{interval,1},app.bigChanges{interval,1},sizeThresh] = getSmallBigChanges(app.change_maps{interval},'mean',0,false);
            [app.smallChanges{interval,2},app.bigChanges{interval,2},sizeThresh] = getSmallBigChanges(app.change_maps{interval},'median',0,false);
            [app.smallChanges{interval,3},app.bigChanges{interval,3},sizeThresh] = getSmallBigChanges(app.change_maps{interval},'manual',30,false);
            [app.smallChanges{interval,4},app.bigChanges{interval,4},sizeThresh] = getSmallBigChanges(app.change_maps{interval},'manual',100,false);
            [app.smallChanges{interval,5},app.bigChanges{interval,5},sizeThresh] = getSmallBigChanges(app.change_maps{interval},'manual',300,false);
         
            % Progress Update
            close(progress);
            app.processedIntervals = [app.processedIntervals, interval];
        end
        
        function displayProcessedImages(app)
            app.AnalyzethisintervalButton.Visible = 'off';
            imshow(app.images1_crop{app.currentInterval}, 'Parent', app.MainImage);
            hold(app.MainImage, 'on');
            app.main_image_transparent_axis = imshow(app.images2_crop_bright{app.currentInterval},'Parent', app.MainImage);
            
            % set Alpha value correctly
            if app.manualMode
                alpha = app.manualModeOpacitySlider.Value / 100;
            else
                [leftval, rightval] = getSliderBoundaries(app, app.Slider.Value);
                alpha = getAlpha(app, app.Slider.Value, leftval, rightval);
            end
            set(app.main_image_transparent_axis,'AlphaData', alpha);
            hold(app.MainImage, 'off');        
        end
        
        function showImageSelectPopup(app, msg)
            answer = questdlg(msg, "Chose Imageset",...
                "Select Image Folder", "Quit Application", "Select Image Folder");
            
            switch answer
                case "Select Image Folder"
                    ChangeimagesetButtonPushed(app, 0);
                case "Quit Application"
                    close(app.UIFigure);
                case ""
                    showImageSelectPopup(app, msg);
            end
        end
        
        function createManualCheckBoxes(app)
            if length(app.previewCheckButtonsManual) > 0
                % delete old checkboxes if new dataset was selected
                for i=1:length(app.previewCheckButtonsManual)
                    delete(app.previewCheckButtonsManual{i})
                end
                app.previewCheckButtonsManual = {};
            end
            majort = app.Slider.MajorTicks;
            limitsl = app.Slider.Limits(2);
            w = app.Slider.Position(3);
            offsetx = app.Slider.Position(1);
            centers = majort ./ limitsl * w + offsetx;
            for i=1:size(app.images, 2)
                chkbx = uicheckbox(app.UIFigure);
                chkbx.Position = [centers(i)-5 630 22 22];
                chkbx.Text = "";
                chkbx.ValueChangedFcn = @app.manualChkboxSelect;
                app.previewCheckButtonsManual{i} = chkbx;
            end
            hideCheckButtons(app, ~app.manualMode);
        end
        
        % callback function on preview checkbox click
        function manualChkboxSelect(app, src, event)
            index = find([app.previewCheckButtonsManual{:}] == src);
            if src.Value == 1
                % case unchecked -> checked
                % check if already 2 elements
                if length(app.checkedIndices) == 2
                    % get index of first element
                    id1 = app.checkedIndices(1)
                    % remove its check in the ui
                    bx = app.previewCheckButtonsManual{id1};
                    bx.Value = 0;
                    % replace first element with second
                    app.checkedIndices(1) = app.checkedIndices(2);
                    % add new element on pos 2
                    app.checkedIndices(2) = index;
                else
                    % append new element
                    app.checkedIndices(end+1) = index;
                end
            else
                % case checked -> unchecked
                % remove unchecked element
                id = find(app.checkedIndices == index);
                % remove element
                app.checkedIndices(id) = [];
            end
            % if two elements selected, display either button or cached
            % results
            if length(app.checkedIndices) == 2
                interval = getInterval(app, app.checkedIndices(1), app.checkedIndices(2));
                if ~isempty(app.processedIntervals(app.processedIntervals == interval))
                   % interval already processed, so display results
                   app.currentInterval = interval;
                   displayProcessedImages(app);
                   % setup checkboxes for changes
                   setup_ui_changeHighlighings(app)
                   % display hightlights colored in image
                   displayClusterChangeMaps(app);
                   % display highlights for small+large changes
                   displaySmallLargeChanges(app);
                else
                    handleAnalyzeButton(app);
                end
            end
        end
        
        function createIntervalMatrix(app)
            s = size(app.images, 2);
            app.intervalMatrix = zeros(s,s);
            count = 0;
            for row=1:s
                for col=1:s
                    if col > row
                       count = count+1;
                       app.intervalMatrix(row,col) = count;
                    end
                end
            end
        end
        
        % gets the "interval-id" for the current image combination
        % according to intervalmatrix
        function interval = getInterval(app, image1_index, image2_index)
            ims = sort([image1_index, image2_index]);
            interval = app.intervalMatrix(ims(1), ims(2));
        end
        
        function [image_id1, image_id2] = getImagesForInterval(app, interval)
            [image_id1, image_id2] = find(app.intervalMatrix == interval);
        end
        
        function handleAnalyzeButton(app)
            if app.ManualButton.Value == 1
                app.AnalyzethisintervalButton.Text = "Compare selected images";
                app.AnalyzethisintervalButton.Visible = 1;
            else
                app.AnalyzethisintervalButton.Text = "Analyze this interval";
                app.AnalyzethisintervalButton.Visible = 0;
            end
        end
        
        function hideCheckButtons(app, hideFlag)
            for i=1:length(app.previewCheckButtonsManual)
                app.previewCheckButtonsManual{i}.Visible = ~hideFlag;
            end
        end
        
        % create checkboxes
        function setup_ui_changeHighlighings(app)
            if length(app.clusterChangesCheckBoxes) > 0
                % delete old checkboxes if new dataset was selected
                for i=1:length(app.clusterChangesCheckBoxes)
                    delete(app.clusterChangesCheckBoxes{i})
                    delete(app.clusterChangesColorBoxes{i,1})
                    delete(app.clusterChangesColorBoxes{i,2})
                end
                app.clusterChangesCheckBoxes = {};
                app.clusterChangesColorBoxes = {};
            end
            % get number of cluster masks
            num_clusters = length(app.cluster_masks_change_map{app.currentInterval});
%             disp("Number of clusters: " + num_clusters);
            x = app.ChangesLabel.Position(1);
            y = app.ChangesLabel.Position(2);
            height = app.ChangesLabel.Position(4);
            width = app.ChangesLabel.Position(3);
            for i=1:num_clusters
                % create new checkboxes for each cluster
                chkbx = uicheckbox(app.UIFigure);
                chkbx.Position = [x y-height width height];
                chkbx.Value = 1;
                chkbx.Text = "Color change from / to:";
                chkbx.ValueChangedFcn = @app.highlightClusterChanges;
                
                app.clusterChangesCheckBoxes{i} = chkbx;
                
                colboxSize = [20, 20]; % rows, colums
                colbox1 = uiimage(app.UIFigure);
                
                colbox1_Data(:,:,1) = ones(colboxSize)*app.pic1_clusters_colors{app.currentInterval}(i,1);
                colbox1_Data(:,:,2) = ones(colboxSize)*app.pic1_clusters_colors{app.currentInterval}(i,2);
                colbox1_Data(:,:,3) = ones(colboxSize)*app.pic1_clusters_colors{app.currentInterval}(i,3);
                
                colbox1.ImageSource = colbox1_Data;
                colbox1.Position = [x+150 y-20 colboxSize(2) colboxSize(1)];
                app.clusterChangesColorBoxes{i,1} = colbox1;
                
                colbox2 = uiimage(app.UIFigure);
                
                colbox2_Data(:,:,1) = ones(colboxSize)*app.pic2_clusters_colors{app.currentInterval}(i,1);
                colbox2_Data(:,:,2) = ones(colboxSize)*app.pic2_clusters_colors{app.currentInterval}(i,2);
                colbox2_Data(:,:,3) = ones(colboxSize)*app.pic2_clusters_colors{app.currentInterval}(i,3);
                
                colbox2.ImageSource = colbox2_Data;
                colbox2.Position = [x+175 y-20 colboxSize(2) colboxSize(1)];
                app.clusterChangesColorBoxes{i,2} = colbox2;
                
                y = y - height;
            end
        end
        
        % callback function for click on check boxes for color changes
        function highlightClusterChanges(app, src, event)
            index = find([app.clusterChangesCheckBoxes{:}] == src);
            if src.Value
                hold(app.MainImage, 'on');
                h = size(app.images1_crop{app.currentInterval}, 1);
                w = size(app.images1_crop{app.currentInterval}, 2);
                
                % Generate RGB Picture
                % color pool:
                colors = [0 0 1; 0 1 0; 1 0 0; 0 1 1; 1 1 0];
                c = colors(index, :);
                rgb = getUniColoredPicture(app, c(1), c(2), c(3));
                
                app.change_map_clusters_axis{index} = imshow(rgb,'Parent', app.MainImage);
                % set alpha value correctly
                alpha = app.OpacitySlider.Value / 100;
                alphamask = alpha * app.cluster_masks_change_map{app.currentInterval}{index}(1:h, 1:w);
                set(app.change_map_clusters_axis{index},'AlphaData', alphamask);
                hold(app.MainImage, 'off');
            else % If box is unchecked
%                 if isgraphics(app.change_map_clusters_axis{index})
                    % Check if change map is existing
                    app.change_map_clusters_axis{index}.Visible = 'off';
%                 end
            end            
        end
        
        function rgb = getUniColoredPicture(app, R, G, B)
            %Generate RGB Picture
            rgb = zeros(size(app.images1_crop{app.currentInterval}));              
            rgb(:,:,1) = R * ones(size(app.images1_crop{app.currentInterval},1),...
                size(app.images1_crop{app.currentInterval},2));
            rgb(:,:,2) = G * ones(size(app.images1_crop{app.currentInterval},1),...
                size(app.images1_crop{app.currentInterval},2));
            rgb(:,:,3) = B * ones(size(app.images1_crop{app.currentInterval},1),...
                size(app.images1_crop{app.currentInterval},2));
        end
        
        % displays colored highlights for changes
        function displayClusterChangeMaps(app)
            l = length(app.clusterChangesCheckBoxes);
            % color pool:
            colors = [0 0 1; 0 1 0; 1 0 0; 0 1 1; 1 1 0];
            alpha = app.OpacitySlider.Value / 100;
            for i=1:length(app.change_map_clusters_axis)
                if isgraphics(app.change_map_clusters_axis{i})
                    delete(app.change_map_clusters_axis{i});
                end
            end
            app.change_map_clusters_axis = {};
            for i=1:length(app.clusterChangesCheckBoxes)
                checked = app.clusterChangesCheckBoxes{i}.Value;                       
                h = size(app.images1_crop{app.currentInterval}, 1);
                w = size(app.images1_crop{app.currentInterval}, 2);
                
                % Generate RGB Picture                        
                c = colors(i, :);
                rgb = getUniColoredPicture(app, c(1), c(2), c(3));
                hold(app.MainImage, 'on');
                app.change_map_clusters_axis{i} = imshow(rgb,'Parent', app.MainImage);
                hold(app.MainImage, 'off');
                % set alpha value correctly
                alphamask = alpha * app.cluster_masks_change_map{app.currentInterval}{i}(1:h, 1:w);
                set(app.change_map_clusters_axis{i},'AlphaData', alphamask);
                if ~checked
                    app.change_map_clusters_axis{i}.Visible = 0;
                end
            end
        end
        
        % displays highlights for small and large changes according to
        % checkbox ticks
        function displaySmallLargeChanges(app)
            % just call the onClick Listeners
            SmallChangesCheckBoxValueChanged(app, 0);
            LargeChangesCheckBoxValueChanged(app, 0);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            message = "Welcome! To start, please select a folder of images";
            app.manualModeOpacitySlider.Visible = 0;
            showImageSelectPopup(app, message);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            handleAnalyzeButton(app);
            if selectedButton.Text == "Manual"
                app.manualMode = 1;
                app.Slider.Enable = 0;
                app.manualModeOpacitySlider.Visible = 1;
                hideCheckButtons(app, 0);
            end
            if selectedButton.Text == "Interactive"
                app.manualMode = 0;
                app.Slider.Enable = 1;
                app.manualModeOpacitySlider.Visible = 0;
                hideCheckButtons(app, 1);
            end
        end

        % Button pushed function: ChangeimagesetButton
        function ChangeimagesetButtonPushed(app, event)
            selpath = uigetdir("","Chose Folder with image series" );
            if selpath
                files = dir(fullfile(selpath, '*.jpg'));
                app.images = {};
                app.filenames = [];
                for i=1:size(files,1)
                    impath = fullfile(files(i).folder, files(i).name);
                    app.filenames = [app.filenames; files(i).name];
                    app.images{i} = im2double(imread(impath));
                    disp("added image " + i);                
                end
                app.filenames = string(app.filenames);
                if size(app.images, 2) == 0
                    showImageSelectPopup(app, "No images found! Please select another folder");
                else
                    for i = 1:length(app.PreviewImages)
                       delete(app.PreviewImages{i});
                    end

                    createThumbnails(app);
                    
                    % initialize image cell data
                    % we need (s*s-s)/2 cells
                    % where s=size(app.images, 2)
                    % (obere Dreiecksmatrix) für alle Kombinationen
                    % createIntervalMatrix() erstellt diese
                    s = size(app.images, 2);
                    app.images1_crop = cell(1, (s*s-s)/2);
                    app.images2_crop = cell(1, (s*s-s)/2);
                    app.images1_crop_gray = cell(1, (s*s-s)/2);
                    app.images2_crop_gray = cell(1, (s*s-s)/2);
                    app.images2_crop_bright = cell(1, (s*s-s)/2);
                    app.images2_crop_bright_gray = cell(1, (s*s-s)/2);
                    app.difference_images = cell(1, (s*s-s)/2);
                    app.difference_images_clean = cell(1, (s*s-s)/2);
                    app.change_maps = cell(1, (s*s-s)/2);
                    
                    createIntervalMatrix(app);
                    
                    % get current interval out of slider position
                    [~,~,im_id1] = getSliderBoundaries(app, app.Slider.Value);
                    interval = getInterval(app, im_id1, im_id1+1);
                    
                    %(Re)set values with new Imageset
                    app.currentInterval = interval;
                    app.processedIntervals = [];
                    
                    pause(.5);   % that thumbnails get displayed before processing
                    processImages(app, interval, "Comparing first two images");
                    
                    % Show the images
                    disableDefaultInteractivity(app.MainImage);
                    displayProcessedImages(app);
                    % setup checkboxes for changes
                    setup_ui_changeHighlighings(app)
                    % display hightlights colored in image
                    displayClusterChangeMaps(app);
                    % display highlights for small+large changes
                    displaySmallLargeChanges(app);
                end
            else
                showImageSelectPopup(app, "Please select a valid folder.");
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            if ~app.manualMode
                changingValue = event.Value;
                [leftval, rightval, leftindex] = getSliderBoundaries(app, changingValue);
                newinterval = getInterval(app, leftindex, leftindex+1);
                
                if newinterval ~= app.currentInterval
                    if ~isempty(app.processedIntervals(app.processedIntervals == newinterval))
                       % interval already processed, so display results
                       app.currentInterval = newinterval;
                       displayProcessedImages(app);
                       % setup checkboxes for changes
                       setup_ui_changeHighlighings(app)
                       % display hightlights colored in image
                       displayClusterChangeMaps(app);
                       % display highlights for small+large changes
                       displaySmallLargeChanges(app);
                    else
                        app.MainImage.Visible = 'off';     % weirdly not working
                        app.AnalyzethisintervalButton.Visible = 'on';
                    end
                else
                    alpha = getAlpha(app, changingValue, leftval, rightval);
                    set(app.main_image_transparent_axis, 'AlphaData', alpha);
                    app.AnalyzethisintervalButton.Visible = 'off';
                end
            end
        end

        % Button pushed function: AnalyzethisintervalButton
        function AnalyzethisintervalButtonPushed(app, event)
            if ~app.manualMode
                % interactive mode
                [~,~,leftindex] = getSliderBoundaries(app, app.Slider.Value);
                interval = getInterval(app, leftindex, leftindex + 1);
                processImages(app, interval, "Analyzing current interval");
                app.currentInterval = interval;
                displayProcessedImages(app);
                % setup checkboxes for changes
                setup_ui_changeHighlighings(app)
                % display hightlights colored in image
                displayClusterChangeMaps(app);
                % display highlights for small+large changes
                displaySmallLargeChanges(app);
            else
                % manual mode
                if length(app.checkedIndices) < 2
                    warndlg("Please select two images");
                else
                    interval = getInterval(app, app.checkedIndices(1), app.checkedIndices(2));
                    app.currentInterval = interval;
                    if ~isempty(app.processedIntervals(app.processedIntervals == interval))
                       % interval already processed, so display results
                       displayProcessedImages(app);
                       % setup checkboxes for changes
                       setup_ui_changeHighlighings(app)
                       % display hightlights colored in image
                       displayClusterChangeMaps(app);
                       % display highlights for small+large changes
                       displaySmallLargeChanges(app);
                    else
                       processImages(app, interval, "Analyzing current interval");
                       displayProcessedImages(app);
                       % setup checkboxes for changes
                       setup_ui_changeHighlighings(app)
                       % display hightlights colored in image
                       displayClusterChangeMaps(app);
                       % display highlights for small+large changes
                       displaySmallLargeChanges(app);
                    end
                end
            end
        end

        % Value changing function: OpacitySlider
        function OpacitySliderValueChanging(app, event)
            changingValue = event.Value;
            alpha = changingValue / 100;
            h = size(app.images1_crop{app.currentInterval}, 1);
            w = size(app.images1_crop{app.currentInterval}, 2);
            for i=1:length(app.cluster_masks_change_map{app.currentInterval})
                alphamask = alpha * app.cluster_masks_change_map{app.currentInterval}{i}(1:h, 1:w);
                set(app.change_map_clusters_axis{i},'AlphaData', alphamask);
            end            
        end

        % Value changing function: manualModeOpacitySlider
        function manualModeOpacitySliderValueChanging(app, event)
            value = event.Value;
            alpha = value/100;
            set(app.main_image_transparent_axis,'AlphaData', alpha);
        end

        % Value changed function: SmallChangesCheckBox
        function SmallChangesCheckBoxValueChanged(app, event)
            value = app.SmallChangesCheckBox.Value;
            if isgraphics(app.small_changes_axis)
                    % Check if change map is existing
                    app.small_changes_axis.Visible = 'off';
            end
            if value
                hold(app.MainImage, 'on');
                h = size(app.images1_crop{app.currentInterval}, 1);
                w = size(app.images1_crop{app.currentInterval}, 2);
                
                % Generate RGB Picture
                rgb = getUniColoredPicture(app, 1, 0, 1);
                
                app.small_changes_axis = imshow(rgb,'Parent', app.MainImage);
                alphamask = app.smallChanges{app.currentInterval,app.sizeThreshModeVal}(1:h, 1:w);
                set(app.small_changes_axis,'AlphaData', alphamask);
                hold(app.MainImage, 'off');

            end       
        end

        % Value changed function: LargeChangesCheckBox
        function LargeChangesCheckBoxValueChanged(app, event)
            value = app.LargeChangesCheckBox.Value;
            if isgraphics(app.large_changes_axis)
                    % Check if change map is existing
                    app.large_changes_axis.Visible = 'off';
            end
            if value
                hold(app.MainImage, 'on');
                h = size(app.images1_crop{app.currentInterval}, 1);
                w = size(app.images1_crop{app.currentInterval}, 2);
                
                % Generate RGB Picture
                rgb = getUniColoredPicture(app, 1, 1, 1);
                
                app.large_changes_axis = imshow(rgb,'Parent', app.MainImage);
                
                alphamask = app.bigChanges{app.currentInterval,app.sizeThreshModeVal}(1:h, 1:w);
                set(app.large_changes_axis,'AlphaData', alphamask);
                hold(app.MainImage, 'off');
            end 
        end

        % Value changed function: AreaSizeThresholdListBox
        function AreaSizeThresholdListBoxValueChanged(app, event)
            value = app.AreaSizeThresholdListBox.Value;
            
            if strcmp(value,'Mean')
                app.sizeThreshModeVal = 1;
            elseif strcmp(value,'Median')
                app.sizeThreshModeVal = 2;
            elseif strcmp(value,'30')
                app.sizeThreshModeVal = 3;
            elseif strcmp(value,'100')
                app.sizeThreshModeVal = 4;
            else 
                app.sizeThreshModeVal = 5;
            end
           LargeChangesCheckBoxValueChanged(app, event); 
           SmallChangesCheckBoxValueChanged(app, event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainImage = uiaxes(app.UIFigure);
            title(app.MainImage, '')
            xlabel(app.MainImage, '')
            ylabel(app.MainImage, '')
            zlabel(app.MainImage, '')
            app.MainImage.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.InteractiveButton = uitogglebutton(app.ButtonGroup);
            app.InteractiveButton.Text = 'Interactive';
            app.InteractiveButton.Value = true;
            app.ManualButton = uitogglebutton(app.ButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ChangeimagesetButton = uibutton(app.UIFigure, 'push');
            app.ChangeimagesetButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeimagesetButtonPushed, true);
            app.ChangeimagesetButton.Text = 'Change image set';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.AnalyzethisintervalButton = uibutton(app.UIFigure, 'push');
            app.AnalyzethisintervalButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzethisintervalButtonPushed, true);
            app.AnalyzethisintervalButton.Visible = 'off';
            app.AnalyzethisintervalButton.Text = 'Analyze this interval';
            app.OpacityLabel = uilabel(app.UIFigure);
            app.OpacityLabel.Text = 'Opacity:';
            app.OpacitySlider = uislider(app.UIFigure);
            app.OpacitySlider.Limits = [10 100];
            app.OpacitySlider.MajorTicks = [];
            app.OpacitySlider.MajorTickLabels = {''};
            app.OpacitySlider.ValueChangingFcn = createCallbackFcn(app, @OpacitySliderValueChanging, true);
            app.OpacitySlider.MinorTicks = [];
            app.OpacitySlider.Value = 50;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.manualModeOpacitySlider = uislider(app.UIFigure);
            app.manualModeOpacitySlider.MajorTicks = [];
            app.manualModeOpacitySlider.ValueChangingFcn = createCallbackFcn(app, @manualModeOpacitySliderValueChanging, true);
            app.manualModeOpacitySlider.MinorTicks = [];
            app.ChangesLabel = uilabel(app.UIFigure);
            app.ChangesLabel.Text = 'Changes:';
            app.SmallChangesCheckBox = uicheckbox(app.UIFigure);
            app.SmallChangesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmallChangesCheckBoxValueChanged, true);
            app.SmallChangesCheckBox.Text = 'Small Changes';
            app.LargeChangesCheckBox = uicheckbox(app.UIFigure);
            app.LargeChangesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LargeChangesCheckBoxValueChanged, true);
            app.LargeChangesCheckBox.Text = 'Large Changes';
            app.G07CV2021Label = uilabel(app.UIFigure);
            app.G07CV2021Label.Text = 'G07 - CV 2021';
            app.AreaSizeThresholdListBoxLabel = uilabel(app.UIFigure);
            app.AreaSizeThresholdListBoxLabel.Text = 'Area Size Threshold';
            app.AreaSizeThresholdListBox = uilistbox(app.UIFigure);
            app.AreaSizeThresholdListBox.Items = {'Mean', 'Median', '30', '100', '300'};
            app.AreaSizeThresholdListBox.ValueChangedFcn = createCallbackFcn(app, @AreaSizeThresholdListBoxValueChanged, true);
            app.AreaSizeThresholdListBox.Value = 'Mean';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end